classdef stepAnalysisModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SamplingRateSpinner           matlab.ui.control.Spinner
        SamplingRateSpinnerLabel      matlab.ui.control.Label
        MovementLabel                 matlab.ui.control.Label
        totalMovementCount            matlab.ui.control.Label
        currentMovementCountIndex     matlab.ui.control.Label
        movementCounterOfLabel        matlab.ui.control.Label
        SectionNameDropDown           matlab.ui.control.DropDown
        SectionNameDropDownLabel      matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        PreviousFileButton            matlab.ui.control.Button
        NextFileButton                matlab.ui.control.Button
        DataSelectorButtonGroup       matlab.ui.container.ButtonGroup
        DataChannelLabelsDropDown     matlab.ui.control.DropDown
        ClassificationLabelsDropDown_2Label  matlab.ui.control.Label
        ClassificationSelectorButtonGroup  matlab.ui.container.ButtonGroup
        ClassificationLabelsDropDown  matlab.ui.control.DropDown
        ClassificationLabelsDropDownLabel  matlab.ui.control.Label
        ClassifyButton                matlab.ui.control.Button
        MetricSelectorButtonGroup     matlab.ui.container.ButtonGroup
        SelectREIButton               matlab.ui.control.Button
        SelectPeakVelocityButton      matlab.ui.control.Button
        SelectRSIButton               matlab.ui.control.Button
        EyeSelectorButtonGroup        matlab.ui.container.ButtonGroup
        RightEyeButton                matlab.ui.control.ToggleButton
        CombinedButton                matlab.ui.control.ToggleButton
        LeftEyeButton                 matlab.ui.control.ToggleButton
        MetricTable                   matlab.ui.control.Table
        nextMovementButton            matlab.ui.control.Button
        previousMovementButton        matlab.ui.control.Button
        LoadDataButton                matlab.ui.control.Button
        totalFileCountLabel           matlab.ui.control.Label
        currentFileIndexLabel         matlab.ui.control.Label
        ofLabel                       matlab.ui.control.Label
        CurrentFileSelectionLabel     matlab.ui.control.Label
        IndividualVelocityPlot        matlab.ui.control.UIAxes
        VelocityEnsemblePlot          matlab.ui.control.UIAxes
        IndividualMovementPlot        matlab.ui.control.UIAxes
        MovementEnsemblePlot          matlab.ui.control.UIAxes
    end

    properties (Access = private)
        currentEyeSelectionIndex = 3; % Default Eye Selection Index (Combined)
        currentFileIndex = 1; % Default Current File Index
        currentFileSectionNames % Table Variable Names Properties
        currentLoadedData % Current Sub-Table Loaded from Main Table
        currentMetrics % Child of TableOfMetrics singular table entry
        currentMetricTableOffset = 0; % Offset Analysis Result Placement in Metric Table
        currentTableOfMetrics % Loaded Table for all Metric Analysis of File
        currentMovementIndex = 1; % Default Current Movement Index
        currentSectionIndex = 3; % Current Position within Table Data
        currentTableData % Presently Loaded Table Data
        dataLoadedBoolean = false; % Default Load Boolean
        mapSectionNameSectionCount; % Map/Value of Section Name to Counts
        mapDataChannelClassification; % Map/Value of Channel to Classification
        mapMetricIndex; % Map of Current Metrics for Horizontal
        nextDataFileButtonPressed = false; % Default Selection Boolean 
        nextMovementButtonPressed = false; % Default Selection Boolean
        previousDataFileButtonPressed = false; % Default Selection Boolean
        previousMovementButtonPressed = false; % Default Selection Boolean
        totalSelectedFiles % Default Total Selected File Objects
        totalCurrentMovements % Maximum Movements for Section Observed
        userSelectedFilePath % User Selected Filepath for Analysis
        userSelectedFile % User Selected Filename(s) for Analysis Cell Array
        
        eyeSelectionMap % Map between button groups and row index
     
    end

    methods (Access = private)

        % Plotting Movement Data (Ensemble, Average, etc.)
        function plotData(app,specificFigureHandle, plotData, isAverageTrace)

            % Set Color and Transparency (Alpha)
            alphaValue = .5;
            lineWidthValue = .5;

            % Set Xlim Based on Sampling Rate
            xlim(specificFigureHandle,[0,round(size(plotData,1)/app.SamplingRateSpinner.Value)])

            % Time Vector
            timeVector = [linspace(0,round(size(plotData,1)/app.SamplingRateSpinner.Value),size(plotData,1))];

            % Ensure Tick Axes are Auto
            xticks(specificFigureHandle,'auto');
            yticks(specificFigureHandle,'auto');

            % Determine Eye Color
            switch app.currentEyeSelectionIndex
                % Left - Green
                case 1
                    colorVal = [0, 1, 0, alphaValue];
                % Right - Red
                case 2
                    colorVal =  [1, 0, 0, alphaValue];
                % Combined - Black
                case 3
                    colorVal = [0, 0, 1, alphaValue];
            end

            % Override Color if AverageTrace
            if(isAverageTrace)
                colorVal = [0 0 0 1];
                lineWidthValue = 1;
            end

            % Plot Data
            plot((specificFigureHandle),timeVector,plotData,'Color', colorVal,'LineWidth',lineWidthValue);
        end

        % Calculate Velocity (First-Order Diff)
        function calculatedVelocity = calculateVelocity(app, movementData)
            calculatedVelocity = [zeros(1,size(movementData,2));movmedian(diff(movementData),10,"Endpoints","shrink")] .* app.SamplingRateSpinner.Value;
        end

        % Replot Ensemble (Upper) Plots
        function replotEnsembles(app)
            % Collect Ensemble Movement Data
            ensembleData = app.extractEnsembleMovementData;

            % Calculate Ensemble of Velocities
            ensembleVelocityData = app.calculateVelocity(ensembleData);

            % Calculate Average Positional Trace
            averageMovement = mean(ensembleData,2);

            % Calculate Average Velocity Trace
            averageVelocity = app.calculateVelocity(averageMovement);

            % Plot Positional First

            % Plot Movement Ensemble
           app.plotData(app.MovementEnsemblePlot,ensembleData, false);

           hold(app.MovementEnsemblePlot,"on")

           % Plot Movement Ensemble Average
           app.plotData(app.MovementEnsemblePlot,averageMovement,true);
           
           hold(app.MovementEnsemblePlot,"off")

           % Plot Velocity Second

            % Plot Movement Ensemble
           app.plotData(app.VelocityEnsemblePlot,ensembleVelocityData, false);

           hold(app.VelocityEnsemblePlot,"on")

           % Plot Movement Ensemble Average
           app.plotData(app.VelocityEnsemblePlot,averageVelocity,true);
           
           hold(app.VelocityEnsemblePlot,"off")
        end

        % Replot Individual (Lower) Plots
        function replotIndividuals(app)
            % Get Current Movement Data from Current Table Data
            currentMovementData = app.currentTableData.(app.SectionNameDropDown.Value){app.currentMovementIndex}.(strcat(app.eyeSelectionMap(app.currentEyeSelectionIndex), " ",app.DataChannelLabelsDropDown.Value));
            
            % Calculate Velocity of Movement Data
            currentVelocityData = app.calculateVelocity(currentMovementData);

            % Plot Movement Data
            app.plotData(app.IndividualMovementPlot,currentMovementData,false);

            % Plot Velocity Data
            app.plotData(app.IndividualVelocityPlot,currentVelocityData,false);
        end

        % Extract movement data from table data
        function extractedMovementData = extractEnsembleMovementData(app)
            
            % Set default values
            extractedMovementData = [];

            % Extract Non-empty cells (cells with movement data)
            selectedMovementData = {app.currentTableData.(app.SectionNameDropDown.Value){~cellfun(@isempty,app.currentTableData.(app.SectionNameDropDown.Value))}}';
            
            % Pull out ALL Data Streams with Good/NaN Classification
            extractedMovementData = app.tableDataExtractor(selectedMovementData,strcat(app.eyeSelectionMap(app.currentEyeSelectionIndex), " ",app.DataChannelLabelsDropDown.Value));
        end

        function extractedTableData = tableDataExtractor(app, cellTableArray, extractionHeader)
            % Determine Largest Sample Length for Plotting
            maxSamplesList = cellfun(@max,cellfun(@size,cellTableArray,'UniformOutput',false));
            maxSamples = max(maxSamplesList);

            % Preallocate Space 
            % Number of Rows based on First Cell Array Value
            % Number of Cols based on Size of Cell Array
            extractedTableData = nan(maxSamples,size(cellTableArray,1));
            for cellIndex = 1:size(cellTableArray,1)
                % Determine if Classification of Specified Data Channel is Good
                if((app.currentTableOfMetrics{cellIndex, app.SectionNameDropDown.Value}{1}.(app.mapDataChannelClassification(app.DataChannelLabelsDropDown.Value))(app.currentEyeSelectionIndex)) ~= 0)
                    extractedTableData(1:maxSamplesList(cellIndex),cellIndex) = cellTableArray{cellIndex}.(extractionHeader);
                end
            end
        end

        % Create table of NaN tables
        function metricTable = createMetricTable(app)
            % Returns # of tables in each variable of tableData
            sectionInstances = values(app.mapSectionNameSectionCount);
            
            % Creating empty table with same dimensions as tableData
            metricTable = array2table(cell(max([sectionInstances{:}]), size(sectionInstances, 2)), "VariableNames",keys(app.mapSectionNameSectionCount));

            % Iterates across sectionInstances (Columns)
            for sectionIndex = 1:size(sectionInstances, 2)

                % Iterates down number of movements within tableData
                for movementIndex = 1:sectionInstances{sectionIndex}

                    % Assign metricTable empty NaN for processing
                    metricTable{movementIndex, sectionIndex} = {array2table(NaN(3,size(app.MetricTable.ColumnName, 1)), "VariableNames", app.MetricTable.ColumnName')};
                end
            end
        end

        % Creates directory for metric table saving
        function metricTablePath = createMetricTableDirectory(~, filepath)
          % Replace Key Processing Words with "ANALYSIS_METRICS"
            metricTablePath = strrep(filepath,"FILTERED_CALIBRATED","ANALYSIS_METRICS");
        end

        % Create Map Container to Map Keys (Section Names) to Values
        % (Movement Counts per Section Name)
        function createSectionNameMap(app)
            % Iterate over each cell in Variable Name
            sectionLogicalCells = cellfun(@(tableVariableName) cellfun(@(sectionData) ~isempty(sectionData),app.currentTableData.(tableVariableName),'UniformOutput',false),app.currentFileSectionNames,'UniformOutput',false);
            sectionMovementCounts = cellfun(@(logicalCell) sum(cell2mat(logicalCell),1), sectionLogicalCells);
            % Map Keys - Section Names to Values - Section Movement Counts 
            app.mapSectionNameSectionCount = containers.Map(app.currentFileSectionNames,sectionMovementCounts);
        end

        % Create NaN table of tables for metric analysis
        function findMetricTable(app,filePath)
            % Retrieve FileName
            fileName = app.returnFileName;
    
            % Check if table exists
            if(isfile(fullfile(app.createMetricTableDirectory(filePath),fileName)))
                app.currentTableOfMetrics = load(fullfile(app.createMetricTableDirectory(filePath),fileName));

            % Else create NaN metric table
            else
                app.currentTableOfMetrics = app.createMetricTable;
            end
        end
           
        % Load Next Movement Data within Current Table Data
        function getNextMovement(app)
            % Evaluate Button Presses
            % Expected Next Button Pressed
            if(app.nextMovementButtonPressed)
                % Check Boundaries and Roll Over to 1 if reached
                if(app.currentMovementIndex + 1 <= app.totalCurrentMovements)
                    app.currentMovementIndex = app.currentMovementIndex + 1;
                else
                    app.currentMovementIndex = 1;
                end    

            % Else Previous Button Pressed
            else
                % Check Boundaries and Roll Over to Last File if reached
                if(app.currentMovementIndex - 1 >= 1)
                    app.currentMovementIndex = app.currentMovementIndex - 1;
                else
                    app.currentMovementIndex = app.totalCurrentMovements;
                end
            end

            % Load Subsection of Table Data
            app.loadSectionOfData;

            % Update Metric Table for New Movement Index
            app.loadMetricTableData;

            % Update Labels
            app.currentMovementCountIndex.Text = string(app.currentMovementIndex);
        end

        function getSectionNames(app)
            % Load Current Table Variable Names
            app.currentFileSectionNames = app.currentTableData.Properties.VariableNames';     
            % Update Dropdown Items
            app.SectionNameDropDown.Items = app.currentFileSectionNames;
            % Set Default Value to First Item
            app.SectionNameDropDown.Value = app.currentFileSectionNames{1};
            % Map Section Names and Movement Counts
            app.createSectionNameMap;
        end

        % Table Data FILE Pathing Retrieval
        function getTablePathing(app)
            % Button was Pressed and App has data
            if(~strcmp(app.CurrentFileSelectionLabel.Text,'Current File Selection'))
                % Check Button Press (Next Default)
                if(app.nextDataFileButtonPressed)
                    % Check Boundaries and Roll Over to 1 if reached
                    if(app.currentFileIndex + 1 <= app.totalSelectedFiles)
                        app.currentFileIndex = app.currentFileIndex + 1;
                    else
                        app.currentFileIndex = 1;
                    end
                % Check Previous Button Press
                elseif(app.previousDataFileButtonPressed)
                    % Check Boundaries and Roll Over to Last File if reached
                    if(app.currentFileIndex - 1 >= 1)
                        app.currentFileIndex = app.currentFileIndex - 1;
                    else
                        app.currentFileIndex = app.totalSelectedFiles;
                    end
                % Section Name Change 
                else
                    app.currentFileIndex = 1;
                end
                
                % Update Labels
                app.currentFileIndexLabel.Text = string(app.currentFileIndex);

            % If no Button Pressed Load via Prompt
            else
                % Select File Pathway and File(s) to Process
                [app.userSelectedFilePath, app.userSelectedFile] = loadData.getTableData();

                % Proceed If Load Successful
                if(~isempty(app.userSelectedFile))
                    % Populate Default Count Variable
                    app.currentFileIndex = 1;
                    app.currentMovementIndex = 1;

                    % Evaluate for Single or Multi-File Selection
                    if(ischar(app.userSelectedFile))
                        app.totalSelectedFiles = 1;
                    else
                        app.totalSelectedFiles = size(app.userSelectedFile,1);
                    end

                    % Set Labels
                    app.currentFileIndexLabel.Text = string(app.currentFileIndex);
                    app.totalFileCountLabel.Text = string(app.totalSelectedFiles); 

                    % Load Table Data
                    app.loadTableData;

                    % Update Load Data Boolean
                    app.dataLoadedBoolean = true;
                end
            end
        end
        
        function loadTableData(app)
            % Load Current Table Path and Filename
            if(ischar(app.userSelectedFile))
                app.currentTableData = loadData.loadTableData(app.userSelectedFilePath,app.userSelectedFile);
                % Update Labels
                app.CurrentFileSelectionLabel.Text = app.userSelectedFile;

            else
                app.currentTableData = loadData.loadTableData(app.userSelectedFilePath,app.userSelectedFile{app.currentFileIndex});
                % Update Labels
                app.CurrentFileSelectionLabel.Text = app.userSelectedFile{app.currentFileIndex};
            end
            % Load Map of Section Names and Expected Counts
            app.getSectionNames;
            % Get Metric Table (New if Does Not Exist or Returns File if
            % Exists)
            app.findMetricTable(app.userSelectedFilePath);
            % Load in Metric Table File
            app.loadMetricTableData;
            % Load in Section Data of File (Default)
            app.loadSectionOfData;

        end

        % Load Metric Table Based on Current Movement Index (Row) and
        % Section Index (Column)
        function loadMetricTableData(app)
           app.currentMetrics = app.currentTableOfMetrics{app.currentMovementIndex,app.currentSectionIndex};  
           app.MetricTable.Data = app.currentMetrics{:};
        end

        function loadSectionOfData(app)
            app.currentLoadedData = app.currentTableData{app.currentMovementIndex,app.currentSectionIndex};
        end

        function fileName = returnFileName(app)
             % Evaluate Single File or Batch
            if(ischar(app.userSelectedFile))
                fileName = app.userSelectedFile;
            else
                fileName = app.userSelectedFile{app.currentFileIndex};
            end
        end

        % Update Labels for New Section
        function resetSectionMovementIndex(app)
            % Load Movement Index
            app.currentMovementIndex = 1;
            app.currentMovementCountIndex.Text = string(app.currentMovementIndex);
            app.totalCurrentMovements = app.mapSectionNameSectionCount(app.SectionNameDropDown.Value);
            app.totalMovementCount.Text = string(app.totalCurrentMovements);
        end

        % Evaluate if Load Data Boolean has been set to true (Data Loaded)
        function booleanFlag = DataLoadedValidation(app)
            if (app.dataLoadedBoolean)
                booleanFlag = app.dataLoadedBoolean;
            % Force Data Load
            else
                app.LoadDataButtonPushed;
                booleanFlag = app.dataLoadedBoolean;
            end
        end

        % Updates Modifications to Current Metric Table
        function updateMetricTable(app)
            app.MetricTable.Data = app.currentMetrics{:};
        end

        % Returns Placement of Current Metric into Table
        function [metricRow, metricColumn] = getMetricPlacement(app,metricName)
            metricRow = app.currentEyeSelectionIndex;
            metricColumn = app.mapMetricIndex(metricName) + app.currentMetricTableOffset;
        end

        % Evaluate Movement Position Response Amplitude from REI - RSI
        function updateResponseAmplitude(app)
            % Get Current Positions of Response Metrics
            [rsiRow, rsiCol] = app.getMetricPlacement("RSI");
            [reiRow, reiCol] = app.getMetricPlacement("REI");

            % Check Values Exist
            rsiBool = isnan(app.currentMetrics{1}(rsiRow,rsiCol).(1));
            reiBool = isnan(app.currentMetrics{1}(reiRow,reiCol).(1));

            % If Both Exist Calculate RA
            if ~rsiBool && ~reiBool
                currentRA = app.IndividualMovementPlot.Children.YData(app.time2samples(app.currentMetrics{1}(reiRow,reiCol).(1))) - app.IndividualMovementPlot.Children.YData(app.time2samples(app.currentMetrics{1}(rsiRow,rsiCol).(1)));
                [raRow, raCol] = app.getMetricPlacement("RA");
                app.currentMetrics{1}(raRow,raCol) = {currentRA};
                app.updateMetricTable;
            end
        end

        % Converts Time Space to Sample Space
        function samplePoint = time2samples(app, timePoint)
            samplePoint = timePoint * app.SamplingRateSpinner.Value;
        end

        % Takes PVI and Calculates PVI
        function updatePeakVelocity(app)
            [pviRow, pviCol] = app.getMetricPlacement("PVI");
            currentPeakVelocity = app.IndividualVelocityPlot.Children.YData(app.time2samples(app.currentMetrics{1}(pviRow,pviCol).(1)));
            [pvRow, pvCol] = app.getMetricPlacement("PV");
            app.currentMetrics{1}(pvRow,pvCol) = {currentPeakVelocity};
            app.updateMetricTable;
        end

        % Calculate Metric Function
        function calculateMetric(app, metricField)
            if(app.DataLoadedValidation())
                % Store UIFigure Visibility State
                handleVisbilityState = app.UIFigure.HandleVisibility;
                % Temp Turn On Visibility
                app.UIFigure.HandleVisibility = 'callback';
                % Set Figure for ginput
                set(0,'CurrentFigure',app.UIFigure)
                
                try
                    % User Selection
                    [indexSelection, ~] = ginput(1);
    
                    % Verify Input
                    if(~isempty(indexSelection))
                        % Value Correction
                        if(mod(indexSelection,1/500)~=0)
                            indexSelection = indexSelection - mod(indexSelection,1/500);
                        end

                        % Assign Value to Metric Table
                        [metricRow, metricCol] = app.getMetricPlacement(metricField);
                        app.currentMetrics{1}(metricRow,metricCol) = {indexSelection};

                        % Update Metric Table
                        app.updateMetricTable;
                       
                    else
                        uialert(app.UIFigure,"Operation Cancelled", "Operation Cancellation",'Icon','warning')
                    end
                catch
                    uialert(app.UIFigure,"Operation Cancelled", "Operation Cancellation",'Icon','warning')
                end
                % Return Visibility State
                app.UIFigure.HandleVisibility = handleVisbilityState;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.eyeSelectionMap = containers.Map([1, 2, 3], ["Left Eye", "Right Eye", "Combined"]);
            app.mapDataChannelClassification = containers.Map(["Horizontal","Vertical","Pupil"],["HClass","VClass","PClass"]);
            app.mapMetricIndex = containers.Map(["RSI","REI","RA","PVI","PV"],[1,2,3,4,5]);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Set Default Title
            app.CurrentFileSelectionLabel.Text = 'Current File Selection';
            % Identify Table Pathing to Load
            app.getTablePathing;
            % Reset Labels for Section
            app.resetSectionMovementIndex;
            % Plot Initial Data
            % Replot Upper Plots
            app.replotEnsembles;
            % Replot Lower Plots
            app.replotIndividuals;
        end

        % Button pushed function: NextFileButton
        function NextFileButtonPushed(app, event)
            % Set Boolean for Button Condition
            app.nextDataFileButtonPressed = true;
            app.getTablePathing;
            app.resetSectionMovementIndex;
            app.loadTableData;
            % Replot Upper Plots
            app.replotEnsembles;
            % Replot Lower Plots
            app.replotIndividuals;
            % Reset Button Condition
            app.nextDataFileButtonPressed = false;
        end

        % Button pushed function: nextMovementButton
        function nextMovementButtonPushed(app, event)
            app.nextMovementButtonPressed = true;
            app.getNextMovement;
            % Replot Lower Plots
            app.replotIndividuals;
            app.nextMovementButtonPressed = false;
        end

        % Button pushed function: previousMovementButton
        function previousMovementButtonPushed(app, event)
            app.previousMovementButtonPressed = true;
            app.getNextMovement;
            % Replot Lower Plots
            app.replotIndividuals;
            app.previousMovementButtonPressed = false;
        end

        % Button pushed function: PreviousFileButton
        function PreviousFileButtonPushed(app, event)
            app.previousDataFileButtonPressed = true;
            app.getTablePathing;
            app.resetSectionMovementIndex
            app.loadTableData;
            % Replot Upper Plots
            app.replotEnsembles;
            % Replot Lower Plots
            app.replotIndividuals;
            app.previousDataFileButtonPressed = false;
        end

        % Value changed function: SectionNameDropDown
        function SectionNameDropDownValueChanged(app, event)
            app.currentSectionIndex = find(strcmp(app.SectionNameDropDown.Value,keys(app.mapSectionNameSectionCount)));
            app.resetSectionMovementIndex;
            app.loadSectionOfData;
            app.loadMetricTableData;
            % Replot Upper Plots
            app.replotEnsembles;
            % Replot Lower Plots
            app.replotIndividuals;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Check if Data is Loaded
            if(app.DataLoadedValidation())
                % Retrieve FileName
                fileName = app.returnFileName;
    
                % Save Metric Table Data to ANALYSIS_METRIC Directory
                saveFilePathway = app.createMetricTableDirectory(app.userSelectedFilePath);
                % Check If Directory Does Not Exist
                if(~exist(saveFilePathway,'dir'))
                    mkdir(saveFilePathway);
                end
                saveFunction.saveData(app.MetricTable,fullfile(saveFilePathway,fileName),"-mat");
            end            
        end

        % Selection changed function: EyeSelectorButtonGroup
        function EyeSelectorButtonGroupSelectionChanged(app, event)
           switch app.EyeSelectorButtonGroup.SelectedObject.Text
                case "Left Eye"
                    app.currentEyeSelectionIndex = 1;
               case "Right Eye"
                    app.currentEyeSelectionIndex = 2;
               case "Combined"
                    app.currentEyeSelectionIndex = 3;
           end
           % Update Data
           app.replotEnsembles;
           % Replot Upper Plots
           app.replotIndividuals;
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            % Replot Upper Plots
            app.replotEnsembles;
        end

        % Button pushed function: SelectRSIButton
        function SelectRSIButtonPushed(app, event)
            % Run Metric Calculation
            app.calculateMetric("RSI");
            
            % Check RA Status
            app.updateResponseAmplitude;
        end

        % Value changed function: DataChannelLabelsDropDown
        function DataChannelLabelsDropDownValueChanged(app, event)
            % Evaluate if Pupil/Horizontal Selected
            if(contains(app.DataChannelLabelsDropDown.Value,"Pupil"))
                app.currentMetricTableOffset = 6;
            else
                app.currentMetricTableOffset = 0;
            end
            % Trigger Replot
            app.replotEnsembles;
            app.replotIndividuals;
        end

        % Button pushed function: SelectREIButton
        function SelectREIButtonPushed(app, event)
            % Run Metric Calculation
            app.calculateMetric("REI");

            % Check RA Status
            app.updateResponseAmplitude;
        end

        % Button pushed function: SelectPeakVelocityButton
        function SelectPeakVelocityButtonPushed(app, event)
            % Run Metric Calculation
            app.calculateMetric("PVI");
            
            % Update PV with PVI
            app.updatePeakVelocity();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MovementEnsemblePlot = uiaxes(app.UIFigure);
            title(app.MovementEnsemblePlot, 'Movement Ensemble Plot')
            xlabel(app.MovementEnsemblePlot, 'Time (Seconds)')
            ylabel(app.MovementEnsemblePlot, 'Final Position')
            zlabel(app.MovementEnsemblePlot, 'Z')
            app.MovementEnsemblePlot.YAxisLocation = 'right';
            app.IndividualMovementPlot = uiaxes(app.UIFigure);
            title(app.IndividualMovementPlot, 'Movement Plot')
            xlabel(app.IndividualMovementPlot, 'Time (Seconds)')
            ylabel(app.IndividualMovementPlot, 'Final Positoon')
            zlabel(app.IndividualMovementPlot, 'Z')
            app.IndividualMovementPlot.YAxisLocation = 'right';
            app.VelocityEnsemblePlot = uiaxes(app.UIFigure);
            title(app.VelocityEnsemblePlot, 'Velocity Ensemble Plot')
            xlabel(app.VelocityEnsemblePlot, 'Time (Seconds)')
            ylabel(app.VelocityEnsemblePlot, 'Velocity')
            zlabel(app.VelocityEnsemblePlot, 'Z')
            app.IndividualVelocityPlot = uiaxes(app.UIFigure);
            title(app.IndividualVelocityPlot, 'Velocity Plot')
            xlabel(app.IndividualVelocityPlot, 'Time (Seconds)')
            ylabel(app.IndividualVelocityPlot, 'Velocity')
            zlabel(app.IndividualVelocityPlot, 'Z')
            app.CurrentFileSelectionLabel = uilabel(app.UIFigure);
            app.CurrentFileSelectionLabel.Text = 'Current File Selection';
            app.ofLabel = uilabel(app.UIFigure);
            app.ofLabel.Text = 'of';
            app.currentFileIndexLabel = uilabel(app.UIFigure);
            app.currentFileIndexLabel.Text = '###';
            app.totalFileCountLabel = uilabel(app.UIFigure);
            app.totalFileCountLabel.Text = '###';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.previousMovementButton = uibutton(app.UIFigure, 'push');
            app.previousMovementButton.ButtonPushedFcn = createCallbackFcn(app, @previousMovementButtonPushed, true);
            app.previousMovementButton.Text = 'PREVIOUS';
            app.nextMovementButton = uibutton(app.UIFigure, 'push');
            app.nextMovementButton.ButtonPushedFcn = createCallbackFcn(app, @nextMovementButtonPushed, true);
            app.nextMovementButton.Text = 'NEXT';
            app.MetricTable = uitable(app.UIFigure);
            app.MetricTable.ColumnName = {'RSI'; 'REI'; 'RA'; 'PVI'; 'PV'; 'FA'; 'RSID'; 'REID'; 'RAD'; 'PDROCI'; 'PDROC'; 'ID'; 'FD'; 'HClass'; 'VClass'; 'PClass'};
            app.MetricTable.RowName = {'Left Eye'; 'Right Eye'; 'Combined'};
            app.MetricTable.SelectionType = 'row';
            app.EyeSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.EyeSelectorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EyeSelectorButtonGroupSelectionChanged, true);
            app.EyeSelectorButtonGroup.Title = 'Eye Selector';
            app.LeftEyeButton = uitogglebutton(app.EyeSelectorButtonGroup);
            app.LeftEyeButton.Text = 'Left Eye';
            app.CombinedButton = uitogglebutton(app.EyeSelectorButtonGroup);
            app.CombinedButton.Text = 'Combined';
            app.CombinedButton.Value = true;
            app.RightEyeButton = uitogglebutton(app.EyeSelectorButtonGroup);
            app.RightEyeButton.Text = 'Right Eye';
            app.MetricSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.MetricSelectorButtonGroup.Title = 'Metric Selector';
            app.SelectRSIButton = uibutton(app.MetricSelectorButtonGroup, 'push');
            app.SelectRSIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRSIButtonPushed, true);
            app.SelectRSIButton.Text = 'Select RSI';
            app.SelectPeakVelocityButton = uibutton(app.MetricSelectorButtonGroup, 'push');
            app.SelectPeakVelocityButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPeakVelocityButtonPushed, true);
            app.SelectPeakVelocityButton.Text = 'Select Peak Velocity';
            app.SelectREIButton = uibutton(app.MetricSelectorButtonGroup, 'push');
            app.SelectREIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectREIButtonPushed, true);
            app.SelectREIButton.Text = 'Select REI';
            app.ClassificationSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.ClassificationSelectorButtonGroup.Title = 'Classification Selector';
            app.ClassifyButton = uibutton(app.ClassificationSelectorButtonGroup, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.ClassificationLabelsDropDownLabel = uilabel(app.ClassificationSelectorButtonGroup);
            app.ClassificationLabelsDropDownLabel.Text = 'Classification Labels';
            app.ClassificationLabelsDropDown = uidropdown(app.ClassificationSelectorButtonGroup);
            app.ClassificationLabelsDropDown.Items = {'Good', 'Bad', 'Asymmetrical', 'Transient Saccade', 'Other'};
            app.ClassificationLabelsDropDown.Editable = 'on';
            app.ClassificationLabelsDropDown.Value = 'Good';
            app.DataSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.DataSelectorButtonGroup.Title = 'Data Selector';
            app.ClassificationLabelsDropDown_2Label = uilabel(app.DataSelectorButtonGroup);
            app.ClassificationLabelsDropDown_2Label.Text = 'Data Channel Labels';
            app.DataChannelLabelsDropDown = uidropdown(app.DataSelectorButtonGroup);
            app.DataChannelLabelsDropDown.Items = {'Horizontal', 'Vertical', 'Pupil'};
            app.DataChannelLabelsDropDown.ValueChangedFcn = createCallbackFcn(app, @DataChannelLabelsDropDownValueChanged, true);
            app.DataChannelLabelsDropDown.Value = 'Horizontal';
            app.NextFileButton = uibutton(app.UIFigure, 'push');
            app.NextFileButton.ButtonPushedFcn = createCallbackFcn(app, @NextFileButtonPushed, true);
            app.NextFileButton.Text = 'Next File';
            app.PreviousFileButton = uibutton(app.UIFigure, 'push');
            app.PreviousFileButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFileButtonPushed, true);
            app.PreviousFileButton.Text = 'Previous File';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SectionNameDropDownLabel = uilabel(app.UIFigure);
            app.SectionNameDropDownLabel.Text = 'Section  Name';
            app.SectionNameDropDown = uidropdown(app.UIFigure);
            app.SectionNameDropDown.ValueChangedFcn = createCallbackFcn(app, @SectionNameDropDownValueChanged, true);
            app.movementCounterOfLabel = uilabel(app.UIFigure);
            app.movementCounterOfLabel.Text = 'of';
            app.currentMovementCountIndex = uilabel(app.UIFigure);
            app.currentMovementCountIndex.Text = '###';
            app.totalMovementCount = uilabel(app.UIFigure);
            app.totalMovementCount.Text = '###';
            app.MovementLabel = uilabel(app.UIFigure);
            app.MovementLabel.Text = 'Movement';
            app.SamplingRateSpinnerLabel = uilabel(app.UIFigure);
            app.SamplingRateSpinnerLabel.Text = 'Sampling Rate';
            app.SamplingRateSpinner = uispinner(app.UIFigure);
            app.SamplingRateSpinner.Limits = [0 10000];
            app.SamplingRateSpinner.RoundFractionalValues = 'on';
            app.SamplingRateSpinner.Value = 500;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stepAnalysisModule

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end